classdef PlateLoaderGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlateLoaderGUIUIFigure  matlab.ui.Figure
        GripperImage            matlab.ui.control.Image
        BarImage                matlab.ui.control.Image
        PlateImage              matlab.ui.control.Image
        BackgroundImage         matlab.ui.control.Image
        ResetButton             matlab.ui.control.Button
        SpecialMovePanel        matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        FibonacciButton         matlab.ui.control.Button
        MovePanel               matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        MoveButton              matlab.ui.control.Button
        FromDropDownLabel       matlab.ui.control.Label
        FromDropDown            matlab.ui.control.DropDown
        ToDropDownLabel         matlab.ui.control.Label
        ToDropDown              matlab.ui.control.DropDown
        ConnectionPanel         matlab.ui.container.Panel
        GridLayout8             matlab.ui.container.GridLayout
        DISCONNECTButton        matlab.ui.control.Button
        COMPortEditField        matlab.ui.control.NumericEditField
        COMPortEditFieldLabel   matlab.ui.control.Label
        CONNECTButton           matlab.ui.control.Button
        ResponsePanel           matlab.ui.container.Panel
        GridLayout7             matlab.ui.container.GridLayout
        TextArea                matlab.ui.control.TextArea
        GetStatusButton         matlab.ui.control.Button
        XAXISPanel              matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        Button_1                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        GripperPanel            matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        OpenButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        ZAXISPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        ExtendButton            matlab.ui.control.Button
        RetractButton           matlab.ui.control.Button
        SetTimeDelaysPanel      matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        DelayTable              matlab.ui.control.Table
        SetTimeDelaysButton     matlab.ui.control.Button
        ResetTimeDelaysButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Com_Port
        Robot
        Delays
        GripperPositions 
        GripperImageSources
        BarPositions 
        PlatePositions
    end
    
    methods (Access = private)
        
        function [] = updateImage(app)
            [xAxisIndex, isExtended, isClosed, platePresent] = app.Robot.getProperties;
            if isExtended
                app.BarImage.Position = app.BarPositions(xAxisIndex + 5,:);
                app.GripperImage.Position = app.GripperPositions(xAxisIndex + 5,:);
%                 if platePresent
%                     app.PlateImage.Position = 
            else 
                app.BarImage.Position = app.BarPositions(xAxisIndex,:);
                app.GripperImage.Position = app.GripperPositions(xAxisIndex,:);
            end
            if isClosed
                app.GripperImage.ImageSource = app.GripperImageSources(2);
            else
                app.GripperImage.ImageSource = app.GripperImageSources(1);
            end
            
            if platePresent
                if isExtended
                    app.PlateImage.Position = app.PlatePositions(xAxisIndex+5,:);
                else
                    app.PlateImage.Position = app.PlatePositions(xAxisIndex,:);
                end
                app.PlateImage.Visible = "on";
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.DelayTable.Data = [60 20 30
            0 30 30
            30 0 30
            30 30 0
            30 20 60];
            app.DelayTable.RowName = {'1','2','3','4','5'};
            app.Delays = [0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
            
            app.GripperImageSources = ["./gripperopen.png", "./gripperclosed.png"];
            % retracted
            app.BarPositions(1, :) = [110,342,78,84]; 
            app.BarPositions(2, :) = [171,342,78,84]; 
            app.BarPositions(3, :) = [234,342,78,84]; 
            app.BarPositions(4, :) = [297,342,78,84]; 
            app.BarPositions(5, :) = [358,342,78,84]; 
            % extended
            app.BarPositions(6, :) = [109,291,78,135]; 
            app.BarPositions(7, :) = [171,291,78,135];
            app.BarPositions(8, :) = [234,291,78,135];
            app.BarPositions(9, :) = [297,291,78,135];
            app.BarPositions(10, :) = [358,291,78,135];
            
            % retracted
            app.GripperPositions(1, :) = [118,314,60,28]; 
            app.GripperPositions(2, :) = [180,314,60,28]; 
            app.GripperPositions(3, :) = [243,314,60,28]; 
            app.GripperPositions(4, :) = [306,314,60,28]; 
            app.GripperPositions(5, :) = [365,314,60,28]; 
            % extended
            app.GripperPositions(6, :) = [118,263,60,28]; 
            app.GripperPositions(7, :) = [180,263,60,28]; 
            app.GripperPositions(8, :) = [243,263,60,28]; 
            app.GripperPositions(9, :) = [306,263,60,28]; 
            app.GripperPositions(10, :) = [365,263,60,28]; 
            
            % retracted
            app.PlatePositions(1, :) = [118,287,57,46];
            app.PlatePositions(2, :) = [180,287,57,46];
            app.PlatePositions(3, :) = [243,287,57,46];
            app.PlatePositions(4, :) = [306,287,57,46];
            app.PlatePositions(5, :) = [365,287,57,46];
            % extended
            app.PlatePositions(6, :) = [118,235,57,46];
            app.PlatePositions(7, :) = [180,235,57,46];
            app.PlatePositions(8, :) = [243,235,57,46];
            app.PlatePositions(9, :) = [306,235,57,46];
            app.PlatePositions(10, :) = [365,235,57,46];


            app.PlateImage.Position = app.PlatePositions(6,:);
            app.PlateImage.Visible = "off";


        end

        % Value changed function: COMPortEditField
        function COMPortEditFieldValueChanged(app, event)
            app.Com_Port = app.COMPortEditField.Value;
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            app.Robot = PlateLoader(app.Com_Port);
            app.TextArea.Value = 'READY';
            app.updateImage
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            app.Robot = PlateLoaderSim;
            app.TextArea.Value = 'READY';
            app.updateImage
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            response = app.Robot.reset;
            app.TextArea.Value = response;
            app.updateImage

            
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            [xAxis, zAxis, gripperClosed, platePresent] = app.Robot.getProperties;
            str1 = 'X-Axis: ';
            str2 = num2str(xAxis);
            str3 = '  Z-Axis:';
            if zAxis
                str4 = ' Extended';
            else
                str4 = ' Retracted';
            end
            str5 = '  Gripper:';
            if gripperClosed
                str6 = ' Closed';
            else
                str6 = ' Open';
            end
            str7 = '  Plate:';
            if platePresent
                str8 = ' Present';
            else
                str8 = ' Not Present';
            end
            app.TextArea.Value = strcat(str1,str2,str3,str4,str5,str6,str7,str8);
            app.updateImage
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            response = app.Robot.x(1);
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            response = app.Robot.x(2);
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            response = app.Robot.x(3);
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            response = app.Robot.x(4);
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            response = app.Robot.x(5);
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            response = app.Robot.retract;
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            response = app.Robot.extend;
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            response = app.Robot.open;
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            response = app.Robot.close;
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            response = app.Robot.movePlate(str2double(app.FromDropDown.Value),str2double(app.ToDropDown.Value));
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: FibonacciButton
        function FibonacciButtonPushed(app, event)
            response = app.Robot.specialMove;
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: ResetTimeDelaysButton
        function ResetTimeDelaysButtonPushed(app, event)
            response = app.Robot.resetDefaultTimes;
            app.DelayTable.Data = [60 20 30
            0 30 30
            30 0 30
            30 30 0
            30 20 60];
            app.TextArea.Value = response;
            app.updateImage
        end

        % Button pushed function: SetTimeDelaysButton
        function SetTimeDelaysButtonPushed(app, event)
            response = app.Robot.setTimeValues([zeros(5,1),app.DelayTable.Data,zeros(5,1)]);
            app.TextArea.Value = response;
            app.updateImage
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlateLoaderGUIUIFigure = uifigure('Visible', 'off');
            app.PlateLoaderGUIUIFigure.Name = 'Plate Loader GUI';
            app.SetTimeDelaysPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.GridLayout5 = uigridlayout(app.SetTimeDelaysPanel);
            app.ResetTimeDelaysButton = uibutton(app.GridLayout5, 'push');
            app.ResetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeDelaysButtonPushed, true);
            app.ResetTimeDelaysButton.Text = 'Reset Time Delays';
            app.SetTimeDelaysButton = uibutton(app.GridLayout5, 'push');
            app.SetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @SetTimeDelaysButtonPushed, true);
            app.SetTimeDelaysButton.Text = 'Set Time Delays';
            app.DelayTable = uitable(app.GridLayout5);
            app.DelayTable.ColumnName = {'2'; '3'; '4'};
            app.DelayTable.RowName = {'1,2,3,4,5'};
            app.DelayTable.ColumnEditable = true;
            app.ZAXISPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ZAXISPanel.Title = 'Z-AXIS';
            app.GridLayout3 = uigridlayout(app.ZAXISPanel);
            app.RetractButton = uibutton(app.GridLayout3, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ExtendButton = uibutton(app.GridLayout3, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.GripperPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.GridLayout2 = uigridlayout(app.GripperPanel);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.XAXISPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.XAXISPanel.Title = 'X-AXIS';
            app.GridLayout = uigridlayout(app.XAXISPanel);
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.ResponsePanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ResponsePanel.Title = 'Response';
            app.GridLayout7 = uigridlayout(app.ResponsePanel);
            app.GetStatusButton = uibutton(app.GridLayout7, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.TextArea = uitextarea(app.GridLayout7);
            app.ConnectionPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.GridLayout8 = uigridlayout(app.ConnectionPanel);
            app.CONNECTButton = uibutton(app.GridLayout8, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.COMPortEditFieldLabel = uilabel(app.GridLayout8);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.GridLayout8, 'numeric');
            app.COMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMPortEditFieldValueChanged, true);
            app.DISCONNECTButton = uibutton(app.GridLayout8, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.MovePanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.MovePanel.Title = 'Move';
            app.GridLayout4 = uigridlayout(app.MovePanel);
            app.ToDropDown = uidropdown(app.GridLayout4);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.Value = '1';
            app.ToDropDownLabel = uilabel(app.GridLayout4);
            app.ToDropDownLabel.Text = 'To';
            app.FromDropDown = uidropdown(app.GridLayout4);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.Value = '1';
            app.FromDropDownLabel = uilabel(app.GridLayout4);
            app.FromDropDownLabel.Text = 'From';
            app.MoveButton = uibutton(app.GridLayout4, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.SpecialMovePanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.SpecialMovePanel.Title = 'Special Move';
            app.GridLayout6 = uigridlayout(app.SpecialMovePanel);
            app.FibonacciButton = uibutton(app.GridLayout6, 'push');
            app.FibonacciButton.ButtonPushedFcn = createCallbackFcn(app, @FibonacciButtonPushed, true);
            app.FibonacciButton.Text = 'Fibonacci';
            app.ResetButton = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BackgroundImage = uiimage(app.PlateLoaderGUIUIFigure);
            app.BackgroundImage.ImageSource = fullfile(pathToMLAPP, 'background.png');
            app.PlateImage = uiimage(app.PlateLoaderGUIUIFigure);
            app.PlateImage.ImageSource = fullfile(pathToMLAPP, 'plate.png');
            app.BarImage = uiimage(app.PlateLoaderGUIUIFigure);
            app.BarImage.ImageSource = fullfile(pathToMLAPP, 'extended.png');
            app.GripperImage = uiimage(app.PlateLoaderGUIUIFigure);
            app.GripperImage.ImageSource = fullfile(pathToMLAPP, 'gripperclosed.png');
            % Show the figure after all components are created
            app.PlateLoaderGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlateLoaderGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlateLoaderGUIUIFigure)
        end
    end
end