classdef ABB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure  matlab.ui.Figure
        SavedPositionsPanel   matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        Save_1_6              matlab.ui.control.Label
        Save_1_5              matlab.ui.control.Label
        Save_1_4              matlab.ui.control.Label
        Save_1_3              matlab.ui.control.Label
        Save_1_2              matlab.ui.control.Label
        Save_1_1              matlab.ui.control.Label
        SaveButton_1          matlab.ui.control.Button
        LoadButton_1          matlab.ui.control.Button
        Save_2_6              matlab.ui.control.Label
        Save_2_5              matlab.ui.control.Label
        Save_2_4              matlab.ui.control.Label
        Save_2_3              matlab.ui.control.Label
        Save_2_2              matlab.ui.control.Label
        Save_2_1              matlab.ui.control.Label
        SaveButton_2          matlab.ui.control.Button
        LoadButton_2          matlab.ui.control.Button
        Save_3_6              matlab.ui.control.Label
        Save_3_5              matlab.ui.control.Label
        Save_3_4              matlab.ui.control.Label
        Save_3_3              matlab.ui.control.Label
        Save_3_2              matlab.ui.control.Label
        Save_3_1              matlab.ui.control.Label
        SaveButton_3          matlab.ui.control.Button
        LoadButton_3          matlab.ui.control.Button
        AngleControlPanel     matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        Joint6Slider          matlab.ui.control.Slider
        Joint6SliderLabel     matlab.ui.control.Label
        Joint5Slider          matlab.ui.control.Slider
        Joint5SliderLabel     matlab.ui.control.Label
        Joint4Slider          matlab.ui.control.Slider
        Joint4SliderLabel     matlab.ui.control.Label
        Joint3Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        SetPositionsPanel     matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        HomeButton            matlab.ui.control.Button
        GoButton              matlab.ui.control.Button
        Joint6EditField       matlab.ui.control.NumericEditField
        Joint6EditFieldLabel  matlab.ui.control.Label
        Joint5EditField       matlab.ui.control.NumericEditField
        Joint5EditFieldLabel  matlab.ui.control.Label
        Joint4EditField       matlab.ui.control.NumericEditField
        Joint4EditFieldLabel  matlab.ui.control.Label
        Joint3EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel  matlab.ui.control.Label
        Joint2EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel  matlab.ui.control.Label
        Joint1EditField       matlab.ui.control.NumericEditField
        Joint1EditFieldLabel  matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices
        jointAngles = [0 0 0 0 0 0];
        save1 = [0 0 0 0 0 0];
        save2 = [0 0 0 0 0 0];
        save3 = [0 0 0 0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            
            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 290, -90, app.jointAngles(1));
            A2 = create_A_matrix(270, 0, 0, app.jointAngles(2));
            A3 = create_A_matrix(70, 0, -90, app.jointAngles(3));
            A4 = create_A_matrix(0, 302, 90, app.jointAngles(4));
            A5 = create_A_matrix(0, 0, -90, app.jointAngles(5));
            A6 = create_A_matrix(0, 72, 0, app.jointAngles(6));

            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            T0_4 = A1 * A2 * A3 * A4;
            T0_5 = A1 * A2 * A3 * A4 * A5;
            T0_6 = A1 * A2 * A3 * A4 * A5 * A6;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;
            link6VerticesWRTground = T0_6 * app.link6Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
            set(app.link6Patch,'Vertices', link6VerticesWRTground(1:3,:)');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [45 30];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-700 700];
            app.axes_arm.YLim = [-700 700];
            app.axes_arm.ZLim = [-300 900];

            % Create vertices for all the patches
            makeStlLink("IRB120L0.stl", app.axes_arm, [67/225 57/225 45/225]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("IRB120L1.stl", app.axes_arm, [221/255 226/255 228/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("IRB120L2.stl", app.axes_arm, [227/255 91/255 34/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("IRB120L3.stl", app.axes_arm, [1 221/255 26/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("IRB120L4.stl", app.axes_arm, [0 159/255 215/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            app.link5Patch = makeStlLink("IRB120L5.stl", app.axes_arm, [5/255 103/255 133/255]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

            app.link6Patch = makeStlLink("IRB120L6.stl", app.axes_arm, [239/255 177/255 212/255]);
            app.link6Vertices = get(app.link6Patch, 'Vertices')';
            app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));

            app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            app.jointAngles(1) = event.Value;
            app.Joint1EditField.Value = event.Value;
            app.updateArm
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            app.jointAngles(2) = event.Value;
            app.Joint2EditField.Value = event.Value;
            app.updateArm
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            app.jointAngles(3) = event.Value;
            app.Joint3EditField.Value = event.Value;
            app.updateArm
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            app.jointAngles(4) = event.Value;
            app.Joint4EditField.Value = event.Value;
            app.updateArm
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            app.jointAngles(5) = event.Value;
            app.Joint5EditField.Value = event.Value;
            app.updateArm
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            app.jointAngles(6) = event.Value;
            app.Joint6EditField.Value = event.Value;
            app.updateArm
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.jointAngles(4) = app.Joint4EditField.Value;
            app.jointAngles(5) = app.Joint5EditField.Value;
            app.jointAngles(6) = app.Joint6EditField.Value;
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
            app.Joint6Slider.Value = app.jointAngles(6);
            app.updateArm
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointAngles = [0 0 0 0 0 0];
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
            app.Joint6Slider.Value = app.jointAngles(6);
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
            app.Joint4EditField.Value = app.jointAngles(4);
            app.Joint5EditField.Value = app.jointAngles(5);
            app.Joint6EditField.Value = app.jointAngles(6);
            app.updateArm
        end

        % Button pushed function: SaveButton_1
        function SaveButton_1Pushed(app, event)
            app.save1(1) = app.jointAngles(1);
            app.save1(2) = app.jointAngles(2);
            app.save1(3) = app.jointAngles(3);
            app.save1(4) = app.jointAngles(4);
            app.save1(5) = app.jointAngles(5);
            app.save1(6) = app.jointAngles(6);
            app.Save_1_1.Text = num2str(app.save1(1));
            app.Save_1_2.Text = num2str(app.save1(2));
            app.Save_1_3.Text = num2str(app.save1(3));
            app.Save_1_4.Text = num2str(app.save1(4));
            app.Save_1_5.Text = num2str(app.save1(5));
            app.Save_1_6.Text = num2str(app.save1(6));
        end

        % Button pushed function: LoadButton_1
        function LoadButton_1Pushed(app, event)
            app.jointAngles(1) = app.save1(1);
            app.jointAngles(2) = app.save1(2);
            app.jointAngles(3) = app.save1(3);
            app.jointAngles(4) = app.save1(4);
            app.jointAngles(5) = app.save1(5);
            app.jointAngles(6) = app.save1(6);
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
            app.Joint6Slider.Value = app.jointAngles(6);
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
            app.Joint4EditField.Value = app.jointAngles(4);
            app.Joint5EditField.Value = app.jointAngles(5);
            app.Joint6EditField.Value = app.jointAngles(6);
            app.updateArm
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.save2(1) = app.jointAngles(1);
            app.save2(2) = app.jointAngles(2);
            app.save2(3) = app.jointAngles(3);
            app.save2(4) = app.jointAngles(4);
            app.save2(5) = app.jointAngles(5);
            app.save2(6) = app.jointAngles(6);
            app.Save_2_1.Text = num2str(app.save2(1));
            app.Save_2_2.Text = num2str(app.save2(2));
            app.Save_2_3.Text = num2str(app.save2(3));
            app.Save_2_4.Text = num2str(app.save2(4));
            app.Save_2_5.Text = num2str(app.save2(5));
            app.Save_2_6.Text = num2str(app.save2(6));
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.jointAngles(1) = app.save2(1);
            app.jointAngles(2) = app.save2(2);
            app.jointAngles(3) = app.save2(3);
            app.jointAngles(4) = app.save2(4);
            app.jointAngles(5) = app.save2(5);
            app.jointAngles(6) = app.save2(6);
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
            app.Joint6Slider.Value = app.jointAngles(6);
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
            app.Joint4EditField.Value = app.jointAngles(4);
            app.Joint5EditField.Value = app.jointAngles(5);
            app.Joint6EditField.Value = app.jointAngles(6);
            app.updateArm
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            app.save3(1) = app.jointAngles(1);
            app.save3(2) = app.jointAngles(2);
            app.save3(3) = app.jointAngles(3);
            app.save3(4) = app.jointAngles(4);
            app.save3(5) = app.jointAngles(5);
            app.save3(6) = app.jointAngles(6);
            app.Save_3_1.Text = num2str(app.save3(1));
            app.Save_3_2.Text = num2str(app.save3(2));
            app.Save_3_3.Text = num2str(app.save3(3));
            app.Save_3_4.Text = num2str(app.save3(4));
            app.Save_3_5.Text = num2str(app.save3(5));
            app.Save_3_6.Text = num2str(app.save3(6));
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            app.jointAngles(1) = app.save3(1);
            app.jointAngles(2) = app.save3(2);
            app.jointAngles(3) = app.save3(3);
            app.jointAngles(4) = app.save3(4);
            app.jointAngles(5) = app.save3(5);
            app.jointAngles(6) = app.save3(6);
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
            app.Joint6Slider.Value = app.jointAngles(6);
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
            app.Joint4EditField.Value = app.jointAngles(4);
            app.Joint5EditField.Value = app.jointAngles(5);
            app.Joint6EditField.Value = app.jointAngles(6);
            app.updateArm
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.SetPositionsPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SetPositionsPanel.Title = 'Set Positions';
            app.GridLayout2 = uigridlayout(app.SetPositionsPanel);
            app.Joint1EditFieldLabel = uilabel(app.GridLayout2);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Joint1EditField.Limits = [-180 180];
            app.Joint2EditFieldLabel = uilabel(app.GridLayout2);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Joint2EditField.Limits = [-180 180];
            app.Joint3EditFieldLabel = uilabel(app.GridLayout2);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Joint3EditField.Limits = [-180 180];
            app.Joint4EditFieldLabel = uilabel(app.GridLayout2);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Joint4EditField.Limits = [-180 180];
            app.Joint5EditFieldLabel = uilabel(app.GridLayout2);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Joint5EditField.Limits = [-180 180];
            app.Joint6EditFieldLabel = uilabel(app.GridLayout2);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Joint6EditField.Limits = [-180 180];
            app.GoButton = uibutton(app.GridLayout2, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HomeButton = uibutton(app.GridLayout2, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.AngleControlPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.AngleControlPanel.Title = 'Angle Control';
            app.GridLayout = uigridlayout(app.AngleControlPanel);
            app.Joint1SliderLabel = uilabel(app.GridLayout);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.GridLayout);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Joint1Slider.MajorTickLabels = {'-180', '-135', '-90', '-45', '0', '45', '90', '135', '180'};
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.GridLayout);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.GridLayout);
            app.Joint2Slider.Limits = [-180 180];
            app.Joint2Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.GridLayout);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.GridLayout);
            app.Joint3Slider.Limits = [-180 180];
            app.Joint3Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.GridLayout);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.GridLayout);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.GridLayout);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.GridLayout);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.GridLayout);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.GridLayout);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.SavedPositionsPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.GridLayout3 = uigridlayout(app.SavedPositionsPanel);
            app.LoadButton_3 = uibutton(app.GridLayout3, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.SaveButton_3 = uibutton(app.GridLayout3, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.Save_3_1 = uilabel(app.GridLayout3);
            app.Save_3_1.Text = '0';
            app.Save_3_2 = uilabel(app.GridLayout3);
            app.Save_3_2.Text = '0';
            app.Save_3_3 = uilabel(app.GridLayout3);
            app.Save_3_3.Text = '0';
            app.Save_3_4 = uilabel(app.GridLayout3);
            app.Save_3_4.Text = '0';
            app.Save_3_5 = uilabel(app.GridLayout3);
            app.Save_3_5.Text = '0';
            app.Save_3_6 = uilabel(app.GridLayout3);
            app.Save_3_6.Text = '0';
            app.LoadButton_2 = uibutton(app.GridLayout3, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.SaveButton_2 = uibutton(app.GridLayout3, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.Save_2_1 = uilabel(app.GridLayout3);
            app.Save_2_1.Text = '0';
            app.Save_2_2 = uilabel(app.GridLayout3);
            app.Save_2_2.Text = '0';
            app.Save_2_3 = uilabel(app.GridLayout3);
            app.Save_2_3.Text = '0';
            app.Save_2_4 = uilabel(app.GridLayout3);
            app.Save_2_4.Text = '0';
            app.Save_2_5 = uilabel(app.GridLayout3);
            app.Save_2_5.Text = '0';
            app.Save_2_6 = uilabel(app.GridLayout3);
            app.Save_2_6.Text = '0';
            app.LoadButton_1 = uibutton(app.GridLayout3, 'push');
            app.LoadButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_1Pushed, true);
            app.LoadButton_1.Text = 'Load';
            app.SaveButton_1 = uibutton(app.GridLayout3, 'push');
            app.SaveButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_1Pushed, true);
            app.SaveButton_1.Text = 'Save';
            app.Save_1_1 = uilabel(app.GridLayout3);
            app.Save_1_1.Text = '0';
            app.Save_1_2 = uilabel(app.GridLayout3);
            app.Save_1_2.Text = '0';
            app.Save_1_3 = uilabel(app.GridLayout3);
            app.Save_1_3.Text = '0';
            app.Save_1_4 = uilabel(app.GridLayout3);
            app.Save_1_4.Text = '0';
            app.Save_1_5 = uilabel(app.GridLayout3);
            app.Save_1_5.Text = '0';
            app.Save_1_6 = uilabel(app.GridLayout3);
            app.Save_1_6.Text = '0';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end