classdef lab6_car_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6MQTTCarUIFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        SensorStreamingPanel         matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        TimestampLabel               matlab.ui.control.Label
        StreamingSensorValuesSwitch  matlab.ui.control.Switch
        StreamingSensorValuesSwitchLabel  matlab.ui.control.Label
        RightLamp                    matlab.ui.control.Lamp
        RightLampLabel               matlab.ui.control.Label
        CenterLamp                   matlab.ui.control.Lamp
        CenterLampLabel              matlab.ui.control.Label
        LeftLamp                     matlab.ui.control.Lamp
        LeftLampLabel                matlab.ui.control.Label
        ModesPanel                   matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        LineFollowingButton          matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        DriveUntilLineButton         matlab.ui.control.Button
        DriveUntilWallButton         matlab.ui.control.Button
        GridLayout5                  matlab.ui.container.GridLayout
        ClickbelowtousethekeyboardLabel  matlab.ui.control.Label
        SpeedSlider                  matlab.ui.control.Slider
        SpeedSliderLabel             matlab.ui.control.Label
        ServoHeadPanel               matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        MiscPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        BeepButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        mqClient
        priorKey = ''
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;
            
            if strcmp(messageType, "voltage")
                messagePayload = messageStruct.payload;
                app.VoltageLabel.Text = ['Voltage: ',num2str(messagePayload),' V'];
            end

            if strcmp(messageType, "line_sensor_reading")
                messagePayload = messageStruct.payload;
                app.TimestampLabel.Text = messagePayload.timestamp;
                app.LeftLamp.Color = messagePayload.left;
                app.CenterLamp.Color = messagePayload.middle;
                app.RightLamp.Color = messagePayload.right;
            end
        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/rumzissc/to_pi", messageJson)
            %app.mqClient.write("chat", messageJson);
            fprintf("Sent: %s\n", messageJson)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            off_campus = "tcp://broker.hivemq.com";
            on_campus = "tcp://mosquitto.csse.rose-hulman.edu";
            app.mqClient = mqttclient(on_campus);
            app.mqClient.subscribe("me435/rumzissc/to_computer", "Callback", @app.myCallback);
        end

        % Key press function: Lab6MQTTCarUIFigure
        function Lab6MQTTCarUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return; % key is being held down, return the same one
            end
            app.priorKey = key;
    
            speed = round(app.SpeedSlider.Value);
            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds",[speed speed speed speed])
            end

            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds",[-speed -speed -speed -speed])
            end

            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds",[-speed speed speed -speed])
            end

            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds",[speed -speed -speed speed])
            end

            if strcmp(key, "a")
                app.sendMessage("set_speeds",[-speed -speed speed speed])
            end

            if strcmp(key, "d")
                app.sendMessage("set_speeds",[speed speed -speed -speed])
            end


        end

        % Key release function: Lab6MQTTCarUIFigure
        function Lab6MQTTCarUIFigureKeyRelease(app, event)
            app.priorKey = '';
            app.sendMessage("stop")

        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep")
        end

        % Value changed function: StreamingSensorValuesSwitch
        function StreamingSensorValuesSwitchValueChanged(app, event)
            value = app.StreamingSensorValuesSwitch.Value;
            
            if strcmp(value, "On")
                app.sendMessage("is_streaming", true);
            else
                app.sendMessage("is_streaming", false);
            end

        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_wall");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendMessage("mode", "none");
        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_line");
        end

        % Button pushed function: LineFollowingButton
        function LineFollowingButtonPushed(app, event)
            app.sendMessage("mode", "line_follow");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab6MQTTCarUIFigure = uifigure('Visible', 'off');
            app.Lab6MQTTCarUIFigure.Name = 'Lab 6 MQTT Car';
            app.Lab6MQTTCarUIFigure.KeyPressFcn = createCallbackFcn(app, @Lab6MQTTCarUIFigureKeyPress, true);
            app.Lab6MQTTCarUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab6MQTTCarUIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.Lab6MQTTCarUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.MiscPanel = uipanel(app.GridLayout);
            app.MiscPanel.Title = 'Misc';
            app.GridLayout2 = uigridlayout(app.MiscPanel);
            app.BeepButton = uibutton(app.GridLayout2, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            app.ServoHeadPanel = uipanel(app.GridLayout);
            app.ServoHeadPanel.Title = 'Servo Head';
            app.GridLayout4 = uigridlayout(app.ServoHeadPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SpeedSliderLabel = uilabel(app.GridLayout5);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.GridLayout5);
            app.SpeedSlider.Value = 40;
            app.ClickbelowtousethekeyboardLabel = uilabel(app.GridLayout5);
            app.ClickbelowtousethekeyboardLabel.Text = 'Click below to use the keyboard';
            app.ModesPanel = uipanel(app.GridLayout);
            app.ModesPanel.Title = 'Modes';
            app.GridLayout7 = uigridlayout(app.ModesPanel);
            app.DriveUntilWallButton = uibutton(app.GridLayout7, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.GridLayout7, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.StopButton = uibutton(app.GridLayout7, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LineFollowingButton = uibutton(app.GridLayout7, 'push');
            app.LineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @LineFollowingButtonPushed, true);
            app.LineFollowingButton.Text = 'Line Following';
            app.SensorStreamingPanel = uipanel(app.GridLayout);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.GridLayout6 = uigridlayout(app.SensorStreamingPanel);
            app.LeftLampLabel = uilabel(app.GridLayout6);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.GridLayout6);
            app.CenterLampLabel = uilabel(app.GridLayout6);
            app.CenterLampLabel.Text = 'Center';
            app.CenterLamp = uilamp(app.GridLayout6);
            app.RightLampLabel = uilabel(app.GridLayout6);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.GridLayout6);
            app.StreamingSensorValuesSwitchLabel = uilabel(app.GridLayout6);
            app.StreamingSensorValuesSwitchLabel.Text = 'Streaming Sensor Values';
            app.StreamingSensorValuesSwitch = uiswitch(app.GridLayout6, 'slider');
            app.StreamingSensorValuesSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSensorValuesSwitchValueChanged, true);
            app.TimestampLabel = uilabel(app.GridLayout6);
            app.TimestampLabel.Text = 'Timestamp';
            % Show the figure after all components are created
            app.Lab6MQTTCarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab6_car_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6MQTTCarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6MQTTCarUIFigure)
        end
    end
end