classdef GPIOMQTTGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SENDLEDsButton     matlab.ui.control.Button
        GreenSwitch        matlab.ui.control.ToggleSwitch
        GreenSwitchLabel   matlab.ui.control.Label
        YellowSwitch       matlab.ui.control.ToggleSwitch
        YellowSwitchLabel  matlab.ui.control.Label
        RedSwitch          matlab.ui.control.ToggleSwitch
        RedSwitchLabel     matlab.ui.control.Label
        CounterLabel       matlab.ui.control.Label
        GreenonButton      matlab.ui.control.Button
        GreenoffButton     matlab.ui.control.Button
        YellowoffButton    matlab.ui.control.Button
        YellowonButton     matlab.ui.control.Button
        RedoffButton       matlab.ui.control.Button
        RedonButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0;
    end
    
    methods (Access = private)
        
            function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
               if messageType == "button"
                   app.counter = app.counter + 1;
                   app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
               end

               if messageType == "reset"
                   app.counter =+ 0;
                   app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
               end


            end
        
            function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/rumzissc/to_pi", messageJson)
            %app.mqClient.write("chat", messageJson);
            fprintf("Sent: %s\n", messageJson)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/rumzissc/#", "Callback", @app.myCallback);
        end

        % Button pushed function: RedonButton
        function RedonButtonPushed(app, event)
            app.sendMessage("red", "on");
        end

        % Button pushed function: RedoffButton
        function RedoffButtonPushed(app, event)
            app.sendMessage("red", "off");
        end

        % Button pushed function: YellowonButton
        function YellowonButtonPushed(app, event)
            app.sendMessage("yellow", 1);
        end

        % Button pushed function: YellowoffButton
        function YellowoffButtonPushed(app, event)
            app.sendMessage("yellow", 0);
        end

        % Button pushed function: GreenoffButton
        function GreenoffButtonPushed(app, event)
            app.sendMessage("green", false);
        end

        % Button pushed function: GreenonButton
        function GreenonButtonPushed(app, event)
            app.sendMessage("green", true);
        end

        % Button pushed function: SENDLEDsButton
        function SENDLEDsButtonPushed(app, event)
            if app.RedSwitch.Value == "On"
                redVal = 1;
            else 
                redVal = 0;
            end

            if app.YellowSwitch.Value == "On"
                yellowVal = 1;
            else 
                yellowVal = 0;
            end
            
            if app.GreenSwitch.Value == "On"
                greenVal = 1;
            else 
                greenVal = 0;
            end
            
            ledArr = [redVal yellowVal greenVal];

            app.sendMessage("leds", ledArr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedonButton = uibutton(app.UIFigure, 'push');
            app.RedonButton.ButtonPushedFcn = createCallbackFcn(app, @RedonButtonPushed, true);
            app.RedonButton.Text = 'Red on';
            app.RedoffButton = uibutton(app.UIFigure, 'push');
            app.RedoffButton.ButtonPushedFcn = createCallbackFcn(app, @RedoffButtonPushed, true);
            app.RedoffButton.Text = 'Red off';
            app.YellowonButton = uibutton(app.UIFigure, 'push');
            app.YellowonButton.ButtonPushedFcn = createCallbackFcn(app, @YellowonButtonPushed, true);
            app.YellowonButton.Text = 'Yellow on';
            app.YellowoffButton = uibutton(app.UIFigure, 'push');
            app.YellowoffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowoffButtonPushed, true);
            app.YellowoffButton.Text = 'Yellow off';
            app.GreenoffButton = uibutton(app.UIFigure, 'push');
            app.GreenoffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenoffButtonPushed, true);
            app.GreenoffButton.Text = 'Green off';
            app.GreenonButton = uibutton(app.UIFigure, 'push');
            app.GreenonButton.ButtonPushedFcn = createCallbackFcn(app, @GreenonButtonPushed, true);
            app.GreenonButton.Text = 'Green on';
            app.CounterLabel = uilabel(app.UIFigure);
            app.CounterLabel.Text = 'Counter';
            app.RedSwitchLabel = uilabel(app.UIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.UIFigure, 'toggle');
            app.YellowSwitchLabel = uilabel(app.UIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.UIFigure, 'toggle');
            app.GreenSwitchLabel = uilabel(app.UIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SENDLEDsButton = uibutton(app.UIFigure, 'push');
            app.SENDLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SENDLEDsButtonPushed, true);
            app.SENDLEDsButton.Text = 'SEND LEDs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPIOMQTTGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end